classdef Control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ScenarioButtonGroup             matlab.ui.container.ButtonGroup
        CruiseButton                    matlab.ui.control.RadioButton
        TransferButton                  matlab.ui.control.RadioButton
        HarbourButton                   matlab.ui.control.RadioButton
        DaysEditFieldLabel              matlab.ui.control.Label
        DaysEditField                   matlab.ui.control.NumericEditField
        BatteryConfigurationPanel       matlab.ui.container.Panel
        BatteryDropDownLabel            matlab.ui.control.Label
        Battery_model                   matlab.ui.control.DropDown
        DCbusvoltageEditFieldLabel      matlab.ui.control.Label
        DCbusvoltageEditField           matlab.ui.control.NumericEditField
        CapacitykWhEditFieldLabel       matlab.ui.control.Label
        CapacitykWhEditField            matlab.ui.control.NumericEditField
        N_series                        matlab.ui.control.Label
        N_parallel                      matlab.ui.control.Label
        N_total                         matlab.ui.control.Label
        seriesLabel                     matlab.ui.control.Label
        parallelLabel                   matlab.ui.control.Label
        totalLabel                      matlab.ui.control.Label
        OriginalMeasurementsPanel       matlab.ui.container.Panel
        PatriaButton                    matlab.ui.control.Button
        EnginesPanel                    matlab.ui.container.Panel
        RPMEditFieldLabel               matlab.ui.control.Label
        nmax                            matlab.ui.control.NumericEditField
        PowerkWEditFieldLabel           matlab.ui.control.Label
        pmax                            matlab.ui.control.NumericEditField
        nmin                            matlab.ui.control.NumericEditField
        pmin                            matlab.ui.control.NumericEditField
        PropellerfactorEditFieldLabel   matlab.ui.control.Label
        PropellerfactorEditField        matlab.ui.control.NumericEditField
        minLabel                        matlab.ui.control.Label
        maxLabel                        matlab.ui.control.Label
        RPMlimitEditFieldLabel          matlab.ui.control.Label
        nlim                            matlab.ui.control.NumericEditField
        WatertreatmentPanel             matlab.ui.container.Panel
        FreshWaterkWEditFieldLabel      matlab.ui.control.Label
        FreshWaterkWEditField           matlab.ui.control.NumericEditField
        WasteWaterkWEditFieldLabel      matlab.ui.control.Label
        WasteWaterkWEditField           matlab.ui.control.NumericEditField
        ElectricalConverterPanel        matlab.ui.container.Panel
        EfficiencyLabel                 matlab.ui.control.Label
        EfficiencyEditField             matlab.ui.control.NumericEditField
        ModelControlPanel               matlab.ui.container.Panel
        init_button                     matlab.ui.control.Button
        calc_button                     matlab.ui.control.Button
        SolarArrayPanel                 matlab.ui.container.Panel
        ManufacturerDropDownLabel       matlab.ui.control.Label
        ManufacturerDropDown            matlab.ui.control.DropDown
        ModelDropDownLabel              matlab.ui.control.Label
        ModelDropDown                   matlab.ui.control.DropDown
        LossLabel                       matlab.ui.control.Label
        LossEditField                   matlab.ui.control.NumericEditField
        CellAmountEditFieldLabel        matlab.ui.control.Label
        CellAmountEditField             matlab.ui.control.NumericEditField
        PeakPowerkWEditFieldLabel       matlab.ui.control.Label
        PeakPowerkWEditField            matlab.ui.control.NumericEditField
        CoverageLabel                   matlab.ui.control.Label
        CoverageEditField               matlab.ui.control.NumericEditField
        AreaEditFieldLabel              matlab.ui.control.Label
        AreaEditField                   matlab.ui.control.NumericEditField
        DeckAream2EditFieldLabel        matlab.ui.control.Label
        DeckAream2EditField             matlab.ui.control.NumericEditField
        StartdateforsunirradianceDatePickerLabel  matlab.ui.control.Label
        StartdateforsunirradianceDatePicker  matlab.ui.control.DatePicker
        WindturbinePanel                matlab.ui.container.Panel
        Windturbine_model               matlab.ui.control.DropDown
        PowerkWLabel                    matlab.ui.control.Label
        PowerkWEditField                matlab.ui.control.NumericEditField
        NumberofturbinesEditFieldLabel  matlab.ui.control.Label
        NumberofturbinesEditField       matlab.ui.control.NumericEditField
        PEMfuelcellsPanel               matlab.ui.container.Panel
        ModelDropDown_2Label            matlab.ui.control.Label
        PEM_model                       matlab.ui.control.DropDown
        NumberofstacksEditFieldLabel    matlab.ui.control.Label
        NumberofstacksEditField         matlab.ui.control.NumericEditField
        Usageduring12hEditFieldLabel    matlab.ui.control.Label
        Usageduring12hEditField         matlab.ui.control.NumericEditField
        HydrogenstoragePanel            matlab.ui.container.Panel
        TankvolumeLiterEditFieldLabel   matlab.ui.control.Label
        TankvolumeLiterEditField        matlab.ui.control.NumericEditField
        TankpressurebarEditFieldLabel   matlab.ui.control.Label
        TankpressurebarEditField        matlab.ui.control.NumericEditField
        FuelmasskgEditFieldLabel        matlab.ui.control.Label
        FuelmasskgEditField             matlab.ui.control.NumericEditField
        EnginePowerEstimationPanel      matlab.ui.container.Panel
        PropellerCurveButton            matlab.ui.control.StateButton
        SimulationresultsPanel          matlab.ui.container.Panel
        PowerButton                     matlab.ui.control.Button
        BatteryButton                   matlab.ui.control.Button
        EnvironmentButton               matlab.ui.control.Button
        RPMButton                       matlab.ui.control.Button
    end

    
    properties (Access = public)
        solar % load solarcell database
        cell % values for solar array
        wind % wind tubines
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load_system('ZEPS')
            set_scenario(app)
            set_sunIrradiance(app)
            app.solar = load('solar.mat', 'solar');
            app.solar = app.solar.solar;
            manufacturerList = categories(app.solar.Manufacturer);
            app.ManufacturerDropDown.Items = manufacturerList;
            app.ManufacturerDropDown.Value = "Solarwatt";
            app.ModelDropDown.Items = app.solar(app.solar.Manufacturer == app.ManufacturerDropDown.Value,:).Model;
            app.ModelDropDown.Value = "Solarwatt 60M style-270";
            app.cell = app.solar(app.solar.Manufacturer == app.ManufacturerDropDown.Value & app.solar.Model == app.ModelDropDown.Value,:);
            app.wind = load("windturbines.mat", "VAWT");
            app.Windturbine_model.Items = app.wind.VAWT.model;
            app.Windturbine_model.Value = "QR6";
            app.wind.current = app.wind.VAWT(app.wind.VAWT.model == app.Windturbine_model.Value,:);
            app.PowerkWEditField.Value = app.wind.current.power / 1000;
            config = [app.CapacitykWhEditField.Value, app.pmax.Value,app.nlim.Value,app.NumberofstacksEditField.Value, app.Usageduring12hEditField.Value,app.PeakPowerkWEditField.Value];
            assignin('base','config',config);
        end

        % Value changed function: Battery_model, 
        % CapacitykWhEditField, DCbusvoltageEditField
        function set_battery(app, event)
           [V,Q, nser, npar] = UpdateBattery(app.Battery_model.Value, app.DCbusvoltageEditField.Value,app.CapacitykWhEditField.Value*1e3,'round',true);
           app.DCbusvoltageEditField.Value = round(V,1);
           app.CapacitykWhEditField.Value = round(Q/1e3,2);
           app.N_series.Text = string(nser);
           app.N_parallel.Text = string(npar);
           app.N_total.Text = string(npar*nser);
        end

        % Selection changed function: ScenarioButtonGroup
        function set_scenario(app, event)
            switch app.ScenarioButtonGroup.SelectedObject.Text
                case 'Cruise'
                    data = get_scenario(1);
                case 'Transfer'
                    data = get_scenario(2);
                case 'Harbour'
                    data = get_scenario(3);
            end            
            assignin('base','data',data);
            app.DaysEditField.Value = days(seconds(data.time(end)+60));            
            set_param('ZEPS', 'StopTime', string(seconds(days(app.DaysEditField.Value))-60));
            save_system('ZEPS');
        end

        % Value changed function: 
        % StartdateforsunirradianceDatePicker
        function set_sunIrradiance(app, event)
            sun = get_irradiance(app.PeakPowerkWEditField.Value, app.LossEditField.Value, app.StartdateforsunirradianceDatePicker.Value);
            assignin('base','sun',sun);
        end

        % Value changed function: LossEditField, 
        % PeakPowerkWEditField
        function set_solarPanels(app, event)
            maxPower = app.PeakPowerkWEditField.Value;
            maxArea = app.DeckAream2EditField.Value;
            N = round(maxPower * 1000 / app.cell.WattPeak);
            area = round(N*app.cell.Area,1);
            app.CellAmountEditField.Value = N;
            app.PeakPowerkWEditField.Value = app.cell.WattPeak / 1000 * N;
            app.AreaEditField.Value = area;
            app.CoverageEditField.Value = round(100*area/maxArea,1);
            set_sunIrradiance(app)
        end

        % Value changed function: NumberofstacksEditField, 
        % PEM_model, Usageduring12hEditField
        function set_fuelcell(app, event)
            UpdateFuelCell(app.PEM_model.Value, app.NumberofstacksEditField.Value,app.Usageduring12hEditField.Value);
        end

        % Value changed function: TankpressurebarEditField, 
        % TankvolumeLiterEditField
        function set_hydrogen(app, event)
            app.FuelmasskgEditField.Value = UpdateHydrogen(app.TankvolumeLiterEditField.Value, app.TankpressurebarEditField.Value);
        end

        % Value changed function: PropellerfactorEditField, nlim, 
        % nmax, nmin, pmax, pmin
        function set_engine(app, event)
            p = get_propellerCurve(app.nmin.Value, app.nmax.Value, app.pmin.Value*1e3, app.pmax.Value*1e3);
            app.PropellerfactorEditField.Value = p/1000;
            app.pmin.Value = round((p/1000 * app.nmin.Value^3),1);
            app.pmax.Value = round((p/1000 * app.nmax.Value^3),1);
            UpdateEngines(p, app.nmin.Value, app.nlim.Value)
        end

        % Value changed function: DaysEditField
        function set_stopTime(app, event)
            set_param('ZEPS', 'StopTime', string(seconds(days(app.DaysEditField.Value))-60));
            save_system('ZEPS');
        end

        % Value changed function: NumberofturbinesEditField, 
        % Windturbine_model
        function set_wind(app, event)
            app.wind.current = app.wind.VAWT(app.wind.VAWT.model == app.Windturbine_model.Value,:);
            currentWindturbine = app.wind.current;
            assignin('base','currentWindturbine',currentWindturbine);
            app.PowerkWEditField.Value = (app.wind.current.power * app.NumberofturbinesEditField.Value)/1000;
            UpdateWindturbine(currentWindturbine)
        end

        % Value changed function: EfficiencyEditField, 
        % FreshWaterkWEditField, WasteWaterkWEditField
        function set_static(app, event)
            UpdateSystem('Fresh Water', app.FreshWaterkWEditField.Value*1000);
            UpdateSystem('Waste Water', app.WasteWaterkWEditField.Value*1000);
            UpdateSystem('Converter', app.EfficiencyEditField.Value/100);
        end

        % Button pushed function: PowerButton
        function mPower(app, event)
            open_system('ZEPS/Measurements/Power')
        end

        % Button pushed function: BatteryButton
        function mBattery(app, event)
            open_system('ZEPS/Measurements/Battery')
        end

        % Button pushed function: EnvironmentButton
        function mEnvironment(app, event)
            open_system('ZEPS/Measurements/Environment')
        end

        % Button pushed function: RPMButton
        function mRPM(app, event)
            open_system('ZEPS/Measurements/RPM')
        end

        % Button pushed function: PatriaButton
        function showPatriaData(app, event)
            edit viewDataset.mlx;
        end

        % Value changed function: ManufacturerDropDown
        function get_manufacturer(app, event)
            manufacturer = app.ManufacturerDropDown.Value;
            app.ModelDropDown.Items = app.solar(app.solar.Manufacturer == manufacturer,:).Model;
            app.ModelDropDown.Value = app.solar(app.solar.Manufacturer == manufacturer,:).Model(1);
        end

        % Value changed function: ModelDropDown
        function get_model(app, event)
            app.cell = app.solar(app.solar.Manufacturer == app.ManufacturerDropDown.Value & app.solar.Model == app.ModelDropDown.Value,:);
            set_solarPanels(app)
            currentSolar = app.cell;
            assignin('base','currentSolar', currentSolar);
        end

        % Value changed function: PropellerCurveButton
        function get_popellerCurve(app, event)
            get_propellerCurve(app.nmin.Value, app.nmax.Value, app.pmin.Value*1e3, app.pmax.Value*1e3,0.5,true);
            app.PropellerCurveButton.Value = 0;
        end

        % Button pushed function: calc_button
        function Calculate(app, event)
            get_model(app);
            set_battery(app);
            set_engine(app);
            set_solarPanels(app);
            set_fuelcell(app);
            set_hydrogen(app);
            set_static(app);
            set_sunIrradiance(app);
            set_wind(app)
            app.init_button.Enable = true;
        end

        % Button pushed function: init_button
        function Init(app, event)
            open_system('ZEPS');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ScenarioButtonGroup = uibuttongroup(app.UIFigure);
            app.ScenarioButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @set_scenario, true);
            app.ScenarioButtonGroup.Title = 'Scenario';
            app.CruiseButton = uiradiobutton(app.ScenarioButtonGroup);
            app.CruiseButton.Text = 'Cruise';
            app.CruiseButton.Value = true;
            app.TransferButton = uiradiobutton(app.ScenarioButtonGroup);
            app.TransferButton.Text = 'Transfer';
            app.HarbourButton = uiradiobutton(app.ScenarioButtonGroup);
            app.HarbourButton.Text = 'Harbour';
            app.DaysEditFieldLabel = uilabel(app.ScenarioButtonGroup);
            app.DaysEditFieldLabel.Text = 'Days';
            app.DaysEditField = uieditfield(app.ScenarioButtonGroup, 'numeric');
            app.DaysEditField.ValueDisplayFormat = '%11.2g';
            app.DaysEditField.ValueChangedFcn = createCallbackFcn(app, @set_stopTime, true);
            app.DaysEditField.Editable = 'off';
            app.BatteryConfigurationPanel = uipanel(app.UIFigure);
            app.BatteryConfigurationPanel.Title = 'Battery Configuration';
            app.BatteryDropDownLabel = uilabel(app.BatteryConfigurationPanel);
            app.BatteryDropDownLabel.Text = 'Battery';
            app.Battery_model = uidropdown(app.BatteryConfigurationPanel);
            app.Battery_model.Items = {'A48100R', 'EASy Marine', 'EASy Marine 100'};
            app.Battery_model.ValueChangedFcn = createCallbackFcn(app, @set_battery, true);
            app.Battery_model.Value = 'EASy Marine 100';
            app.DCbusvoltageEditFieldLabel = uilabel(app.BatteryConfigurationPanel);
            app.DCbusvoltageEditFieldLabel.Text = 'DC bus voltage';
            app.DCbusvoltageEditField = uieditfield(app.BatteryConfigurationPanel, 'numeric');
            app.DCbusvoltageEditField.Limits = [0 1500];
            app.DCbusvoltageEditField.ValueChangedFcn = createCallbackFcn(app, @set_battery, true);
            app.DCbusvoltageEditField.Value = 800;
            app.CapacitykWhEditFieldLabel = uilabel(app.BatteryConfigurationPanel);
            app.CapacitykWhEditFieldLabel.Text = 'Capacity (kWh)';
            app.CapacitykWhEditField = uieditfield(app.BatteryConfigurationPanel, 'numeric');
            app.CapacitykWhEditField.Limits = [0 20000];
            app.CapacitykWhEditField.ValueChangedFcn = createCallbackFcn(app, @set_battery, true);
            app.CapacitykWhEditField.Value = 4000;
            app.N_series = uilabel(app.BatteryConfigurationPanel);
            app.N_series.Text = '-';
            app.N_parallel = uilabel(app.BatteryConfigurationPanel);
            app.N_parallel.Text = '-';
            app.N_total = uilabel(app.BatteryConfigurationPanel);
            app.N_total.Text = '-';
            app.seriesLabel = uilabel(app.BatteryConfigurationPanel);
            app.seriesLabel.Text = 'series';
            app.parallelLabel = uilabel(app.BatteryConfigurationPanel);
            app.parallelLabel.Text = 'parallel';
            app.totalLabel = uilabel(app.BatteryConfigurationPanel);
            app.totalLabel.Text = 'total';
            app.OriginalMeasurementsPanel = uipanel(app.UIFigure);
            app.OriginalMeasurementsPanel.Title = 'Original Measurements';
            app.PatriaButton = uibutton(app.OriginalMeasurementsPanel, 'push');
            app.PatriaButton.ButtonPushedFcn = createCallbackFcn(app, @showPatriaData, true);
            app.PatriaButton.Text = 'Patria';
            app.EnginesPanel = uipanel(app.UIFigure);
            app.EnginesPanel.Title = 'Engines';
            app.RPMEditFieldLabel = uilabel(app.EnginesPanel);
            app.RPMEditFieldLabel.Text = 'RPM';
            app.nmax = uieditfield(app.EnginesPanel, 'numeric');
            app.nmax.ValueChangedFcn = createCallbackFcn(app, @set_engine, true);
            app.nmax.Value = 1500;
            app.PowerkWEditFieldLabel = uilabel(app.EnginesPanel);
            app.PowerkWEditFieldLabel.Text = 'Power (kW)';
            app.pmax = uieditfield(app.EnginesPanel, 'numeric');
            app.pmax.ValueDisplayFormat = '%.1f';
            app.pmax.ValueChangedFcn = createCallbackFcn(app, @set_engine, true);
            app.pmax.Value = 600;
            app.nmin = uieditfield(app.EnginesPanel, 'numeric');
            app.nmin.ValueChangedFcn = createCallbackFcn(app, @set_engine, true);
            app.nmin.Value = 600;
            app.pmin = uieditfield(app.EnginesPanel, 'numeric');
            app.pmin.ValueDisplayFormat = '%.1f';
            app.pmin.ValueChangedFcn = createCallbackFcn(app, @set_engine, true);
            app.pmin.Value = 36;
            app.PropellerfactorEditFieldLabel = uilabel(app.EnginesPanel);
            app.PropellerfactorEditFieldLabel.Text = 'Propeller factor';
            app.PropellerfactorEditField = uieditfield(app.EnginesPanel, 'numeric');
            app.PropellerfactorEditField.ValueDisplayFormat = '%6.5g';
            app.PropellerfactorEditField.ValueChangedFcn = createCallbackFcn(app, @set_engine, true);
            app.PropellerfactorEditField.Editable = 'off';
            app.minLabel = uilabel(app.EnginesPanel);
            app.minLabel.Text = 'min';
            app.maxLabel = uilabel(app.EnginesPanel);
            app.maxLabel.Text = 'max';
            app.RPMlimitEditFieldLabel = uilabel(app.EnginesPanel);
            app.RPMlimitEditFieldLabel.Text = 'RPM limit';
            app.nlim = uieditfield(app.EnginesPanel, 'numeric');
            app.nlim.ValueDisplayFormat = '%6.5g';
            app.nlim.ValueChangedFcn = createCallbackFcn(app, @set_engine, true);
            app.nlim.Value = 1200;
            app.WatertreatmentPanel = uipanel(app.UIFigure);
            app.WatertreatmentPanel.Title = 'Water treatment';
            app.FreshWaterkWEditFieldLabel = uilabel(app.WatertreatmentPanel);
            app.FreshWaterkWEditFieldLabel.Text = 'Fresh Water (kW)';
            app.FreshWaterkWEditField = uieditfield(app.WatertreatmentPanel, 'numeric');
            app.FreshWaterkWEditField.Limits = [0 1500];
            app.FreshWaterkWEditField.ValueChangedFcn = createCallbackFcn(app, @set_static, true);
            app.FreshWaterkWEditField.Value = 2.3;
            app.WasteWaterkWEditFieldLabel = uilabel(app.WatertreatmentPanel);
            app.WasteWaterkWEditFieldLabel.Text = 'Waste Water (kW)';
            app.WasteWaterkWEditField = uieditfield(app.WatertreatmentPanel, 'numeric');
            app.WasteWaterkWEditField.Limits = [0 1500];
            app.WasteWaterkWEditField.ValueChangedFcn = createCallbackFcn(app, @set_static, true);
            app.WasteWaterkWEditField.Value = 1;
            app.ElectricalConverterPanel = uipanel(app.UIFigure);
            app.ElectricalConverterPanel.Title = 'Electrical Converter';
            app.EfficiencyLabel = uilabel(app.ElectricalConverterPanel);
            app.EfficiencyLabel.Text = 'Efficiency (%)';
            app.EfficiencyEditField = uieditfield(app.ElectricalConverterPanel, 'numeric');
            app.EfficiencyEditField.Limits = [0 100];
            app.EfficiencyEditField.ValueDisplayFormat = '%11.4g ';
            app.EfficiencyEditField.ValueChangedFcn = createCallbackFcn(app, @set_static, true);
            app.EfficiencyEditField.Value = 98;
            app.ModelControlPanel = uipanel(app.UIFigure);
            app.ModelControlPanel.Title = 'Model Control';
            app.init_button = uibutton(app.ModelControlPanel, 'push');
            app.init_button.ButtonPushedFcn = createCallbackFcn(app, @Init, true);
            app.init_button.Enable = 'off';
            app.init_button.Text = 'Init Model';
            app.calc_button = uibutton(app.ModelControlPanel, 'push');
            app.calc_button.ButtonPushedFcn = createCallbackFcn(app, @Calculate, true);
            app.calc_button.Text = 'Calculate';
            app.SolarArrayPanel = uipanel(app.UIFigure);
            app.SolarArrayPanel.Title = 'Solar Array';
            app.ManufacturerDropDownLabel = uilabel(app.SolarArrayPanel);
            app.ManufacturerDropDownLabel.Text = 'Manufacturer';
            app.ManufacturerDropDown = uidropdown(app.SolarArrayPanel);
            app.ManufacturerDropDown.Items = {'undefined'};
            app.ManufacturerDropDown.ValueChangedFcn = createCallbackFcn(app, @get_manufacturer, true);
            app.ManufacturerDropDown.Value = 'undefined';
            app.ModelDropDownLabel = uilabel(app.SolarArrayPanel);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.SolarArrayPanel);
            app.ModelDropDown.Items = {'undefined'};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @get_model, true);
            app.ModelDropDown.Value = 'undefined';
            app.LossLabel = uilabel(app.SolarArrayPanel);
            app.LossLabel.Text = 'Loss (%)';
            app.LossEditField = uieditfield(app.SolarArrayPanel, 'numeric');
            app.LossEditField.ValueChangedFcn = createCallbackFcn(app, @set_solarPanels, true);
            app.LossEditField.Value = 20;
            app.CellAmountEditFieldLabel = uilabel(app.SolarArrayPanel);
            app.CellAmountEditFieldLabel.Text = 'Cell Amount';
            app.CellAmountEditField = uieditfield(app.SolarArrayPanel, 'numeric');
            app.CellAmountEditField.Editable = 'off';
            app.PeakPowerkWEditFieldLabel = uilabel(app.SolarArrayPanel);
            app.PeakPowerkWEditFieldLabel.Text = 'Peak Power (kW)';
            app.PeakPowerkWEditField = uieditfield(app.SolarArrayPanel, 'numeric');
            app.PeakPowerkWEditField.ValueDisplayFormat = '%.1f';
            app.PeakPowerkWEditField.ValueChangedFcn = createCallbackFcn(app, @set_solarPanels, true);
            app.PeakPowerkWEditField.Value = 60;
            app.CoverageLabel = uilabel(app.SolarArrayPanel);
            app.CoverageLabel.Text = 'Coverage (%)';
            app.CoverageEditField = uieditfield(app.SolarArrayPanel, 'numeric');
            app.CoverageEditField.Editable = 'off';
            app.AreaEditFieldLabel = uilabel(app.SolarArrayPanel);
            app.AreaEditFieldLabel.Text = 'Area';
            app.AreaEditField = uieditfield(app.SolarArrayPanel, 'numeric');
            app.AreaEditField.Editable = 'off';
            app.DeckAream2EditFieldLabel = uilabel(app.SolarArrayPanel);
            app.DeckAream2EditFieldLabel.Text = 'Deck Area (m^2)';
            app.DeckAream2EditField = uieditfield(app.SolarArrayPanel, 'numeric');
            app.DeckAream2EditField.Value = 450;
            app.StartdateforsunirradianceDatePickerLabel = uilabel(app.SolarArrayPanel);
            app.StartdateforsunirradianceDatePickerLabel.Text = 'Start date for sun irradiance ';
            app.StartdateforsunirradianceDatePicker = uidatepicker(app.SolarArrayPanel);
            app.StartdateforsunirradianceDatePicker.Limits = [datetime([0 1 1]) datetime([2016 12 31])];
            app.StartdateforsunirradianceDatePicker.DisplayFormat = 'dd-MMM-uuuu';
            app.StartdateforsunirradianceDatePicker.ValueChangedFcn = createCallbackFcn(app, @set_sunIrradiance, true);
            app.StartdateforsunirradianceDatePicker.Value = datetime([2016 5 1]);
            app.WindturbinePanel = uipanel(app.UIFigure);
            app.WindturbinePanel.Title = 'Wind turbine';
            app.Windturbine_model = uidropdown(app.WindturbinePanel);
            app.Windturbine_model.Items = {'undefined'};
            app.Windturbine_model.ValueChangedFcn = createCallbackFcn(app, @set_wind, true);
            app.Windturbine_model.Value = 'undefined';
            app.PowerkWLabel = uilabel(app.WindturbinePanel);
            app.PowerkWLabel.Text = 'Power (kW)';
            app.PowerkWEditField = uieditfield(app.WindturbinePanel, 'numeric');
            app.PowerkWEditField.Editable = 'off';
            app.NumberofturbinesEditFieldLabel = uilabel(app.WindturbinePanel);
            app.NumberofturbinesEditFieldLabel.Text = 'Number of turbines';
            app.NumberofturbinesEditField = uieditfield(app.WindturbinePanel, 'numeric');
            app.NumberofturbinesEditField.RoundFractionalValues = 'on';
            app.NumberofturbinesEditField.ValueChangedFcn = createCallbackFcn(app, @set_wind, true);
            app.NumberofturbinesEditField.Value = 1;
            app.PEMfuelcellsPanel = uipanel(app.UIFigure);
            app.PEMfuelcellsPanel.Title = ' PEM fuel cells';
            app.ModelDropDown_2Label = uilabel(app.PEMfuelcellsPanel);
            app.ModelDropDown_2Label.Text = 'Model';
            app.PEM_model = uidropdown(app.PEMfuelcellsPanel);
            app.PEM_model.Items = {'NEDSTACK FCS 7-XXL', 'NEDSTACK FCS 10-XXL', 'NEDSTACK FCS 13-XXL'};
            app.PEM_model.ValueChangedFcn = createCallbackFcn(app, @set_fuelcell, true);
            app.PEM_model.Value = 'NEDSTACK FCS 13-XXL';
            app.NumberofstacksEditFieldLabel = uilabel(app.PEMfuelcellsPanel);
            app.NumberofstacksEditFieldLabel.Text = 'Number of stacks';
            app.NumberofstacksEditField = uieditfield(app.PEMfuelcellsPanel, 'numeric');
            app.NumberofstacksEditField.RoundFractionalValues = 'on';
            app.NumberofstacksEditField.ValueChangedFcn = createCallbackFcn(app, @set_fuelcell, true);
            app.NumberofstacksEditField.Value = 15;
            app.Usageduring12hEditFieldLabel = uilabel(app.PEMfuelcellsPanel);
            app.Usageduring12hEditFieldLabel.Text = 'Usage during 12h (%)';
            app.Usageduring12hEditField = uieditfield(app.PEMfuelcellsPanel, 'numeric');
            app.Usageduring12hEditField.Limits = [0 100];
            app.Usageduring12hEditField.ValueChangedFcn = createCallbackFcn(app, @set_fuelcell, true);
            app.Usageduring12hEditField.Value = 8;
            app.HydrogenstoragePanel = uipanel(app.UIFigure);
            app.HydrogenstoragePanel.Title = 'Hydrogen storage';
            app.TankvolumeLiterEditFieldLabel = uilabel(app.HydrogenstoragePanel);
            app.TankvolumeLiterEditFieldLabel.Text = 'Tank volume (Liter)';
            app.TankvolumeLiterEditField = uieditfield(app.HydrogenstoragePanel, 'numeric');
            app.TankvolumeLiterEditField.Limits = [0 Inf];
            app.TankvolumeLiterEditField.ValueDisplayFormat = '%11.7g';
            app.TankvolumeLiterEditField.ValueChangedFcn = createCallbackFcn(app, @set_hydrogen, true);
            app.TankvolumeLiterEditField.Value = 6000;
            app.TankpressurebarEditFieldLabel = uilabel(app.HydrogenstoragePanel);
            app.TankpressurebarEditFieldLabel.Text = 'Tank pressure (bar)';
            app.TankpressurebarEditField = uieditfield(app.HydrogenstoragePanel, 'numeric');
            app.TankpressurebarEditField.Limits = [0 500];
            app.TankpressurebarEditField.ValueChangedFcn = createCallbackFcn(app, @set_hydrogen, true);
            app.TankpressurebarEditField.Value = 300;
            app.FuelmasskgEditFieldLabel = uilabel(app.HydrogenstoragePanel);
            app.FuelmasskgEditFieldLabel.Text = 'Fuel mass (kg)';
            app.FuelmasskgEditField = uieditfield(app.HydrogenstoragePanel, 'numeric');
            app.FuelmasskgEditField.ValueDisplayFormat = '%11.6g';
            app.FuelmasskgEditField.Editable = 'off';
            app.EnginePowerEstimationPanel = uipanel(app.UIFigure);
            app.EnginePowerEstimationPanel.Title = 'Engine Power Estimation';
            app.PropellerCurveButton = uibutton(app.EnginePowerEstimationPanel, 'state');
            app.PropellerCurveButton.ValueChangedFcn = createCallbackFcn(app, @get_popellerCurve, true);
            app.PropellerCurveButton.Text = 'Propeller Curve';
            app.SimulationresultsPanel = uipanel(app.UIFigure);
            app.SimulationresultsPanel.Title = 'Simulation results';
            app.PowerButton = uibutton(app.SimulationresultsPanel, 'push');
            app.PowerButton.ButtonPushedFcn = createCallbackFcn(app, @mPower, true);
            app.PowerButton.Text = 'Power';
            app.BatteryButton = uibutton(app.SimulationresultsPanel, 'push');
            app.BatteryButton.ButtonPushedFcn = createCallbackFcn(app, @mBattery, true);
            app.BatteryButton.Text = 'Battery';
            app.EnvironmentButton = uibutton(app.SimulationresultsPanel, 'push');
            app.EnvironmentButton.ButtonPushedFcn = createCallbackFcn(app, @mEnvironment, true);
            app.EnvironmentButton.Text = 'Environment';
            app.RPMButton = uibutton(app.SimulationresultsPanel, 'push');
            app.RPMButton.ButtonPushedFcn = createCallbackFcn(app, @mRPM, true);
            app.RPMButton.Text = 'RPM';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Control

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end